classdef robot_control_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LoadWorkspaceButton    matlab.ui.control.Button
        ModelStausSwitchLabel  matlab.ui.control.Label
        ModelStausSwitch       matlab.ui.control.ToggleSwitch
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
        UIAxes_6               matlab.ui.control.UIAxes
        UIAxes_8               matlab.ui.control.UIAxes
        UIAxes_7               matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function results =  display_camera(app)
            Camera = evalin('base','Camera');
            frame = snapshot(Camera);
            im = image(app.UIAxes2_2,zeros(size(frame),'uint8'));
            axis(app.UIAxes2_2,'image');
            preview(Camera,im)
        end
        
        function results = plot_PWM(app)
            duty_cycle = [7.5 7.5 7.5 7.5 7.5 7.5];
            y = [5 5 0 0];
            
            x=[5 duty_cycle(1) duty_cycle(1) 10];
            plot(app.UIAxes,x,y,'r');
            
            x=[5 duty_cycle(2) duty_cycle(2) 10];
            plot(app.UIAxes_5,x,y,'r');
            
            x=[5 duty_cycle(3) duty_cycle(3) 10];
            plot(app.UIAxes_3,x,y,'r');
            
            x=[5 duty_cycle(4) duty_cycle(4) 10];
            plot(app.UIAxes_6,x,y,'r');
            
            x=[5 duty_cycle(5) duty_cycle(5) 10];
            plot(app.UIAxes_8,x,y,'r');
            
            x=[5 duty_cycle(6) duty_cycle(6) 10];
            plot(app.UIAxes_7,x,y,'r');
        end
        
        function results = plot_Robot(app)
            robot = evalin('base','robot');
            configuration = randomConfiguration(robot);
            axis(app.UIAxes2);
            show(robot, configuration)
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadWorkspaceButton
        function Load_Workspace(app, event)
            evalin('base','Robot_control_script');
            display_camera(app);
            
            plot_PWM(app)
            
            plot_Robot(app)


        end

        % Value changed function: ModelStausSwitch
        function Model_Status(app, event)
            value = app.ModelStausSwitch.Value;
            
            if(value=="On")
                sim('Robot_control',inf)
            else
                set_param(gcs, 'SimulationCommand', 'stop')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.LoadWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @Load_Workspace, true);
            app.LoadWorkspaceButton.Text = 'Load Workspace';
            app.ModelStausSwitchLabel = uilabel(app.UIFigure);
            app.ModelStausSwitchLabel.Text = 'Model Staus';
            app.ModelStausSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ModelStausSwitch.ValueChangedFcn = createCallbackFcn(app, @Model_Status, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Robot Joint/Link Positions')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ZTickLabel = {'0'; '0.5'; '1'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PWM1')
            xlabel(app.UIAxes, '% Duty Cycle')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [5 10];
            app.UIAxes.YLim = [0 6];
            app.UIAxes.XTick = [5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 2 4 6];
            app.UIAxes.YTickLabel = {'0'; '2'; '4'; '6'};
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'PWM3')
            xlabel(app.UIAxes_3, '% Duty Cycle')
            ylabel(app.UIAxes_3, 'Amplitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [5 10];
            app.UIAxes_3.YLim = [0 6];
            app.UIAxes_3.XTick = [5 6 7 8 9 10];
            app.UIAxes_3.YTick = [0 2 4 6];
            app.UIAxes_3.YTickLabel = {'0'; '2'; '4'; '6'};
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'PWM2')
            xlabel(app.UIAxes_5, '% Duty Cycle')
            ylabel(app.UIAxes_5, 'Amplitude')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XLim = [5 10];
            app.UIAxes_5.YLim = [0 6];
            app.UIAxes_5.YTick = [0 2 4 6];
            app.UIAxes_5.YTickLabel = {'0'; '2'; '4'; '6'};
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Camera')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.ZTickLabel = {'0'; '0.5'; '1'};
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'PWM4')
            xlabel(app.UIAxes_6, '% Duty Cycle')
            ylabel(app.UIAxes_6, 'Amplitude')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XLim = [5 10];
            app.UIAxes_6.YLim = [0 6];
            app.UIAxes_6.XTick = [5 6 7 8 9 10];
            app.UIAxes_6.XTickLabel = {'5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_6.YTick = [0 2 4 6];
            app.UIAxes_6.YTickLabel = {'0'; '2'; '4'; '6'};
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'PWM5')
            xlabel(app.UIAxes_8, '% Duty Cycle')
            ylabel(app.UIAxes_8, 'Amplitude')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XLim = [5 10];
            app.UIAxes_8.YLim = [0 6];
            app.UIAxes_8.XTick = [5 6 7 8 9 10];
            app.UIAxes_8.XTickLabel = {'5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_8.YTick = [0 2 4 6];
            app.UIAxes_8.YTickLabel = {'0'; '2'; '4'; '6'};
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'PWM6')
            xlabel(app.UIAxes_7, '% Duty Cycle')
            ylabel(app.UIAxes_7, 'Amplitude')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XLim = [5 10];
            app.UIAxes_7.YLim = [0 6];
            app.UIAxes_7.XTick = [5 6 7 8 9 10];
            app.UIAxes_7.YTick = [0 2 4 6];
            app.UIAxes_7.YTickLabel = {'0'; '2'; '4'; '6'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_control_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end